classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GenelTab                     matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        DHTablosuPanel               matlab.ui.container.Panel
        EditField_IK_yaw             matlab.ui.control.NumericEditField
        IKYawLabel                   matlab.ui.control.Label
        EditField_IK_pitch           matlab.ui.control.NumericEditField
        IKPitchLabel                 matlab.ui.control.Label
        EditField_IK_roll            matlab.ui.control.NumericEditField
        IKRollLabel                  matlab.ui.control.Label
        IKAktifEklemSaysLabel        matlab.ui.control.Label
        IKMaksimumterLabel           matlab.ui.control.Label
        IKZLabel                     matlab.ui.control.Label
        IKYLabel                     matlab.ui.control.Label
        IKXLabel                     matlab.ui.control.Label
        EditField_IK_active_joint    matlab.ui.control.NumericEditField
        EditField_IK_max_iter        matlab.ui.control.NumericEditField
        EditField_IK_z               matlab.ui.control.NumericEditField
        EditField_IK_y               matlab.ui.control.NumericEditField
        EditField_IK_x               matlab.ui.control.NumericEditField
        TersKinematikHesaplaButton   matlab.ui.control.Button
        EditField_JV_7               matlab.ui.control.NumericEditField
        EditField_JV_6               matlab.ui.control.NumericEditField
        EditField_JV_5               matlab.ui.control.NumericEditField
        EditField_JV_4               matlab.ui.control.NumericEditField
        EditField_JV_3               matlab.ui.control.NumericEditField
        EditField_JV_2               matlab.ui.control.NumericEditField
        EditField_JV_1               matlab.ui.control.NumericEditField
        EditField_JV_0               matlab.ui.control.NumericEditField
        ButtonGroup_7                matlab.ui.container.ButtonGroup
        Button_N_7                   matlab.ui.control.RadioButton
        Button_P_7                   matlab.ui.control.RadioButton
        Button_R_7                   matlab.ui.control.RadioButton
        ButtonGroup_6                matlab.ui.container.ButtonGroup
        Button_N_6                   matlab.ui.control.RadioButton
        Button_P_6                   matlab.ui.control.RadioButton
        Button_R_6                   matlab.ui.control.RadioButton
        ButtonGroup_5                matlab.ui.container.ButtonGroup
        Button_N_5                   matlab.ui.control.RadioButton
        Button_P_5                   matlab.ui.control.RadioButton
        Button_R_5                   matlab.ui.control.RadioButton
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        Button_N_4                   matlab.ui.control.RadioButton
        Button_P_4                   matlab.ui.control.RadioButton
        Button_R_4                   matlab.ui.control.RadioButton
        ButtonGroup_3                matlab.ui.container.ButtonGroup
        Button_N_3                   matlab.ui.control.RadioButton
        Button_P_3                   matlab.ui.control.RadioButton
        Button_R_3                   matlab.ui.control.RadioButton
        ButtonGroup_2                matlab.ui.container.ButtonGroup
        Button_N_2                   matlab.ui.control.RadioButton
        Button_P_2                   matlab.ui.control.RadioButton
        Button_R_2                   matlab.ui.control.RadioButton
        ButtonGroup_1                matlab.ui.container.ButtonGroup
        Button_N_1                   matlab.ui.control.RadioButton
        Button_P_1                   matlab.ui.control.RadioButton
        Button_R_1                   matlab.ui.control.RadioButton
        ButtonGroup_0                matlab.ui.container.ButtonGroup
        Button_N_0                   matlab.ui.control.RadioButton
        Button_P_0                   matlab.ui.control.RadioButton
        Button_R_0                   matlab.ui.control.RadioButton
        SliderVariable_7             matlab.ui.control.Slider
        SliderVariable_6             matlab.ui.control.Slider
        SliderVariable_5             matlab.ui.control.Slider
        SliderVariable_4             matlab.ui.control.Slider
        SliderVariable_3             matlab.ui.control.Slider
        SliderVariable_2             matlab.ui.control.Slider
        SliderVariable_1             matlab.ui.control.Slider
        JointVariableLabel           matlab.ui.control.Label
        SliderVariable_0             matlab.ui.control.Slider
        HesaplaButton                matlab.ui.control.Button
        EklemlerLabel                matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        dLabel                       matlab.ui.control.Label
        aLabel                       matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        EklemTipiLabel               matlab.ui.control.Label
        EditField_7alpha             matlab.ui.control.NumericEditField
        EditField_7a                 matlab.ui.control.NumericEditField
        EditField_7d                 matlab.ui.control.NumericEditField
        EditField_7theta             matlab.ui.control.NumericEditField
        EditField_6alpha             matlab.ui.control.NumericEditField
        EditField_6a                 matlab.ui.control.NumericEditField
        EditField_6d                 matlab.ui.control.NumericEditField
        EditField_6theta             matlab.ui.control.NumericEditField
        EditField_5alpha             matlab.ui.control.NumericEditField
        EditField_5a                 matlab.ui.control.NumericEditField
        EditField_5d                 matlab.ui.control.NumericEditField
        EditField_5theta             matlab.ui.control.NumericEditField
        EditField_4alpha             matlab.ui.control.NumericEditField
        EditField_4a                 matlab.ui.control.NumericEditField
        EditField_4d                 matlab.ui.control.NumericEditField
        EditField_4theta             matlab.ui.control.NumericEditField
        EditField_3alpha             matlab.ui.control.NumericEditField
        EditField_3a                 matlab.ui.control.NumericEditField
        EditField_3d                 matlab.ui.control.NumericEditField
        EditField_3theta             matlab.ui.control.NumericEditField
        EditField_2alpha             matlab.ui.control.NumericEditField
        EditField_2a                 matlab.ui.control.NumericEditField
        EditField_2d                 matlab.ui.control.NumericEditField
        EditField_2theta             matlab.ui.control.NumericEditField
        EditField_1alpha             matlab.ui.control.NumericEditField
        EditField_1a                 matlab.ui.control.NumericEditField
        EditField_1d                 matlab.ui.control.NumericEditField
        EditField_1theta             matlab.ui.control.NumericEditField
        EditField_0theta             matlab.ui.control.NumericEditField
        EditField_0d                 matlab.ui.control.NumericEditField
        EditField_0a                 matlab.ui.control.NumericEditField
        EditField_0alpha             matlab.ui.control.NumericEditField
        HyundaiYL012Tab              matlab.ui.container.Tab
        Panel_2                      matlab.ui.container.Panel
        UIAxes_hy                    matlab.ui.control.UIAxes
        DHTablosuHyundaiYL012Panel   matlab.ui.container.Panel
        SimlasyonButton              matlab.ui.control.Button
        EditField_JV_hy_7            matlab.ui.control.NumericEditField
        EditField_JV_hy_6            matlab.ui.control.NumericEditField
        EditField_JV_hy_5            matlab.ui.control.NumericEditField
        EditField_JV_hy_4            matlab.ui.control.NumericEditField
        EditField_JV_hy_3            matlab.ui.control.NumericEditField
        EditField_JV_hy_2            matlab.ui.control.NumericEditField
        EditField_JV_hy_1            matlab.ui.control.NumericEditField
        EditField_JV_hy              matlab.ui.control.NumericEditField
        SliderVariable_hy_7          matlab.ui.control.Slider
        SliderVariable_hy_6          matlab.ui.control.Slider
        SliderVariable_hy_5          matlab.ui.control.Slider
        SliderVariable_hy_4          matlab.ui.control.Slider
        SliderVariable_hy_3          matlab.ui.control.Slider
        SliderVariable_hy_2          matlab.ui.control.Slider
        SliderVariable_hy_1          matlab.ui.control.Slider
        JointVariableLabel_2         matlab.ui.control.Label
        SliderVariable_hy_0          matlab.ui.control.Slider
        HesaplaButton_Hyundai        matlab.ui.control.Button
        EklemlerLabel_2              matlab.ui.control.Label
        Label_20                     matlab.ui.control.Label
        Label_19                     matlab.ui.control.Label
        Label_18                     matlab.ui.control.Label
        Label_17                     matlab.ui.control.Label
        Label_16                     matlab.ui.control.Label
        Label_15                     matlab.ui.control.Label
        Label_14                     matlab.ui.control.Label
        Label_13                     matlab.ui.control.Label
        Label_12                     matlab.ui.control.Label
        dLabel_2                     matlab.ui.control.Label
        aLabel_2                     matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        EditField_7alpha_hy          matlab.ui.control.NumericEditField
        EditField_7a_hy              matlab.ui.control.NumericEditField
        EditField_7d_hy              matlab.ui.control.NumericEditField
        EditField_7theta_hy          matlab.ui.control.NumericEditField
        EditField_6alpha_hy          matlab.ui.control.NumericEditField
        EditField_6a_hy              matlab.ui.control.NumericEditField
        EditField_6d_hy              matlab.ui.control.NumericEditField
        EditField_6theta_hy          matlab.ui.control.NumericEditField
        EditField_5alpha_hy          matlab.ui.control.NumericEditField
        EditField_5a_hy              matlab.ui.control.NumericEditField
        EditField_5d_hy              matlab.ui.control.NumericEditField
        EditField_5theta_hy          matlab.ui.control.NumericEditField
        EditField_4alpha_hy          matlab.ui.control.NumericEditField
        EditField_4a_hy              matlab.ui.control.NumericEditField
        EditField_4d_hy              matlab.ui.control.NumericEditField
        EditField_4theta_hy          matlab.ui.control.NumericEditField
        EditField_3alpha_hy          matlab.ui.control.NumericEditField
        EditField_3a_hy              matlab.ui.control.NumericEditField
        EditField_3d_hy              matlab.ui.control.NumericEditField
        EditField_3theta_hy          matlab.ui.control.NumericEditField
        EditField_2alpha_hy          matlab.ui.control.NumericEditField
        EditField_2a_hy              matlab.ui.control.NumericEditField
        EditField_2d_hy              matlab.ui.control.NumericEditField
        EditField_2theta_hy          matlab.ui.control.NumericEditField
        EditField_1alpha_hy          matlab.ui.control.NumericEditField
        EditField_1a_hy              matlab.ui.control.NumericEditField
        EditField_1d_hy              matlab.ui.control.NumericEditField
        EditField_1theta_hy          matlab.ui.control.NumericEditField
        EditField_0theta_hy          matlab.ui.control.NumericEditField
        EditField_0d_hy              matlab.ui.control.NumericEditField
        EditField_0a_hy              matlab.ui.control.NumericEditField
        EditField_0alpha_hy          matlab.ui.control.NumericEditField
        TransformasyonMatrisleriTab  matlab.ui.container.Tab
        txtOlarakKaydetButton        matlab.ui.control.Button
        Jacobian                     matlab.ui.container.Panel
        Jacobian_65                  matlab.ui.control.EditField
        Jacobian_66                  matlab.ui.control.EditField
        Jacobian_67                  matlab.ui.control.EditField
        Jacobian_68                  matlab.ui.control.EditField
        Jacobian_55                  matlab.ui.control.EditField
        Jacobian_45                  matlab.ui.control.EditField
        Jacobian_35                  matlab.ui.control.EditField
        Jacobian_25                  matlab.ui.control.EditField
        Jacobian_15                  matlab.ui.control.EditField
        Jacobian_56                  matlab.ui.control.EditField
        Jacobian_57                  matlab.ui.control.EditField
        Jacobian_58                  matlab.ui.control.EditField
        Jacobian_46                  matlab.ui.control.EditField
        Jacobian_36                  matlab.ui.control.EditField
        Jacobian_26                  matlab.ui.control.EditField
        Jacobian_16                  matlab.ui.control.EditField
        Jacobian_47                  matlab.ui.control.EditField
        Jacobian_48                  matlab.ui.control.EditField
        Jacobian_37                  matlab.ui.control.EditField
        Jacobian_27                  matlab.ui.control.EditField
        Jacobian_17                  matlab.ui.control.EditField
        Jacobian_38                  matlab.ui.control.EditField
        Jacobian_28                  matlab.ui.control.EditField
        Jacobian_18                  matlab.ui.control.EditField
        Jacobian_64                  matlab.ui.control.EditField
        Jacobian_54                  matlab.ui.control.EditField
        Jacobian_51                  matlab.ui.control.EditField
        Jacobian_61                  matlab.ui.control.EditField
        Jacobian_52                  matlab.ui.control.EditField
        Jacobian_53                  matlab.ui.control.EditField
        Jacobian_62                  matlab.ui.control.EditField
        Jacobian_63                  matlab.ui.control.EditField
        Jacobian_14                  matlab.ui.control.EditField
        Jacobian_24                  matlab.ui.control.EditField
        Jacobian_34                  matlab.ui.control.EditField
        Jacobian_44                  matlab.ui.control.EditField
        Jacobian_13                  matlab.ui.control.EditField
        Jacobian_23                  matlab.ui.control.EditField
        Jacobian_33                  matlab.ui.control.EditField
        Jacobian_43                  matlab.ui.control.EditField
        Jacobian_12                  matlab.ui.control.EditField
        Jacobian_22                  matlab.ui.control.EditField
        Jacobian_32                  matlab.ui.control.EditField
        Jacobian_42                  matlab.ui.control.EditField
        Jacobian_41                  matlab.ui.control.EditField
        Jacobian_31                  matlab.ui.control.EditField
        Jacobian_21                  matlab.ui.control.EditField
        Jacobian_11                  matlab.ui.control.EditField
        T78Panel                     matlab.ui.container.Panel
        TM7_30                       matlab.ui.control.EditField
        TM7_31                       matlab.ui.control.EditField
        TM7_32                       matlab.ui.control.EditField
        TM7_33                       matlab.ui.control.EditField
        TM7_20                       matlab.ui.control.EditField
        TM7_21                       matlab.ui.control.EditField
        TM7_22                       matlab.ui.control.EditField
        TM7_23                       matlab.ui.control.EditField
        TM7_10                       matlab.ui.control.EditField
        TM7_11                       matlab.ui.control.EditField
        TM7_12                       matlab.ui.control.EditField
        TM7_13                       matlab.ui.control.EditField
        TM7_03                       matlab.ui.control.EditField
        TM7_02                       matlab.ui.control.EditField
        TM7_01                       matlab.ui.control.EditField
        TM7_00                       matlab.ui.control.EditField
        T67Panel                     matlab.ui.container.Panel
        TM6_30                       matlab.ui.control.EditField
        TM6_31                       matlab.ui.control.EditField
        TM6_32                       matlab.ui.control.EditField
        TM6_33                       matlab.ui.control.EditField
        TM6_20                       matlab.ui.control.EditField
        TM6_21                       matlab.ui.control.EditField
        TM6_22                       matlab.ui.control.EditField
        TM6_23                       matlab.ui.control.EditField
        TM6_10                       matlab.ui.control.EditField
        TM6_11                       matlab.ui.control.EditField
        TM6_12                       matlab.ui.control.EditField
        TM6_13                       matlab.ui.control.EditField
        TM6_03                       matlab.ui.control.EditField
        TM6_02                       matlab.ui.control.EditField
        TM6_01                       matlab.ui.control.EditField
        TM6_00                       matlab.ui.control.EditField
        T56Panel                     matlab.ui.container.Panel
        TM5_30                       matlab.ui.control.EditField
        TM5_31                       matlab.ui.control.EditField
        TM5_32                       matlab.ui.control.EditField
        TM5_33                       matlab.ui.control.EditField
        TM5_20                       matlab.ui.control.EditField
        TM5_21                       matlab.ui.control.EditField
        TM5_22                       matlab.ui.control.EditField
        TM5_23                       matlab.ui.control.EditField
        TM5_10                       matlab.ui.control.EditField
        TM5_11                       matlab.ui.control.EditField
        TM5_12                       matlab.ui.control.EditField
        TM5_13                       matlab.ui.control.EditField
        TM5_03                       matlab.ui.control.EditField
        TM5_02                       matlab.ui.control.EditField
        TM5_01                       matlab.ui.control.EditField
        TM5_00                       matlab.ui.control.EditField
        T45Panel                     matlab.ui.container.Panel
        TM4_30                       matlab.ui.control.EditField
        TM4_31                       matlab.ui.control.EditField
        TM4_32                       matlab.ui.control.EditField
        TM4_33                       matlab.ui.control.EditField
        TM4_20                       matlab.ui.control.EditField
        TM4_21                       matlab.ui.control.EditField
        TM4_22                       matlab.ui.control.EditField
        TM4_23                       matlab.ui.control.EditField
        TM4_10                       matlab.ui.control.EditField
        TM4_11                       matlab.ui.control.EditField
        TM4_12                       matlab.ui.control.EditField
        TM4_13                       matlab.ui.control.EditField
        TM4_03                       matlab.ui.control.EditField
        TM4_02                       matlab.ui.control.EditField
        TM4_01                       matlab.ui.control.EditField
        TM4_00                       matlab.ui.control.EditField
        T34Panel                     matlab.ui.container.Panel
        TM3_30                       matlab.ui.control.EditField
        TM3_31                       matlab.ui.control.EditField
        TM3_32                       matlab.ui.control.EditField
        TM3_33                       matlab.ui.control.EditField
        TM3_20                       matlab.ui.control.EditField
        TM3_21                       matlab.ui.control.EditField
        TM3_22                       matlab.ui.control.EditField
        TM3_23                       matlab.ui.control.EditField
        TM3_10                       matlab.ui.control.EditField
        TM3_11                       matlab.ui.control.EditField
        TM3_12                       matlab.ui.control.EditField
        TM3_13                       matlab.ui.control.EditField
        TM3_03                       matlab.ui.control.EditField
        TM3_02                       matlab.ui.control.EditField
        TM3_01                       matlab.ui.control.EditField
        TM3_00                       matlab.ui.control.EditField
        T23Panel                     matlab.ui.container.Panel
        TM2_30                       matlab.ui.control.EditField
        TM2_31                       matlab.ui.control.EditField
        TM2_32                       matlab.ui.control.EditField
        TM2_33                       matlab.ui.control.EditField
        TM2_20                       matlab.ui.control.EditField
        TM2_21                       matlab.ui.control.EditField
        TM2_22                       matlab.ui.control.EditField
        TM2_23                       matlab.ui.control.EditField
        TM2_10                       matlab.ui.control.EditField
        TM2_11                       matlab.ui.control.EditField
        TM2_12                       matlab.ui.control.EditField
        TM2_13                       matlab.ui.control.EditField
        TM2_03                       matlab.ui.control.EditField
        TM2_02                       matlab.ui.control.EditField
        TM2_01                       matlab.ui.control.EditField
        TM2_00                       matlab.ui.control.EditField
        T12Panel                     matlab.ui.container.Panel
        TM1_30                       matlab.ui.control.EditField
        TM1_31                       matlab.ui.control.EditField
        TM1_32                       matlab.ui.control.EditField
        TM1_33                       matlab.ui.control.EditField
        TM1_20                       matlab.ui.control.EditField
        TM1_21                       matlab.ui.control.EditField
        TM1_22                       matlab.ui.control.EditField
        TM1_23                       matlab.ui.control.EditField
        TM1_10                       matlab.ui.control.EditField
        TM1_11                       matlab.ui.control.EditField
        TM1_12                       matlab.ui.control.EditField
        TM1_13                       matlab.ui.control.EditField
        TM1_03                       matlab.ui.control.EditField
        TM1_02                       matlab.ui.control.EditField
        TM1_01                       matlab.ui.control.EditField
        TM1_00                       matlab.ui.control.EditField
        T01Panel                     matlab.ui.container.Panel
        TM0_30                       matlab.ui.control.EditField
        TM0_31                       matlab.ui.control.EditField
        TM0_32                       matlab.ui.control.EditField
        TM0_33                       matlab.ui.control.EditField
        TM0_20                       matlab.ui.control.EditField
        TM0_21                       matlab.ui.control.EditField
        TM0_22                       matlab.ui.control.EditField
        TM0_23                       matlab.ui.control.EditField
        TM0_10                       matlab.ui.control.EditField
        TM0_11                       matlab.ui.control.EditField
        TM0_12                       matlab.ui.control.EditField
        TM0_13                       matlab.ui.control.EditField
        TM0_03                       matlab.ui.control.EditField
        TM0_02                       matlab.ui.control.EditField
        TM0_01                       matlab.ui.control.EditField
        TM0_00                       matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function tf_to_tftab(app, matrices)
            for i = 0:7 % İsimlendirme 0'a göre
                for j = 0:3
                    for k = 0:3
                        index_box_name = sprintf('TM%d_%d%d', i, j, k);
                        assignin('base', 'matrices_index',matrices{i+1}(k+1, j+1));
                        app.(index_box_name).Value = sprintf('%.5g', matrices{i+1}(k+1,j+1));
                    end
                end
                
            end
            
        end
        
        function calculate_total_tf(app, matrices)
            total_transformation_matrix = 1;
            for i = 1:size(matrices)
                total_transformation_matrix = total_transformation_matrix * matrices{i};
            end
            assignin('base', 'total_matrix',total_transformation_matrix);

        end
        
        function draw_graphic(app, points)
                fig = app.UIFigure;
                fig.Name = 'Matlab UI Örneği';
                
                ax  = app.UIAxes;
                
                plot3(ax, points(1, 1), points(2, 1), points(3, 1), 'o-');
                hold(ax, 'on');

                for i = 2:size(points, 2)
                    radio_button_name_R = sprintf('Button_R_%d', i);
                    radio_button_name_P = sprintf('Button_P_%d', i);
                    radio_button_name_N = sprintf('Button_N_%d', i);
                
                    if radio_button_name_R
                        plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'ro-');
                    elseif radio_button_name_P
                        plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'ro-');
                    elseif radio_button_name_N
                        plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'go-');
                    end
                end
                hold(ax, 'off');

                grid(app.UIAxes, 'on');
                xlabel(ax, 'X Ekseni');
                ylabel(ax, 'Y Ekseni');
                zlabel(ax, 'Z Ekseni');
                title(ax, 'Robot');

            end
        
            function points = calculate_joint_points(app, matrices, initial_point)
                assignin('base', 'initial_point', initial_point);
                points = initial_point;
                assignin('base', 'size_of_matrices', size(matrices, 2));
                for i = 1:size(matrices, 2)
                    %new_point = matrices{i} * points(:,end);
                    new_point = matrices{i} * initial_point;
                    assignin('base', 'new_point', new_point);
                    assignin('base', 'point_end', points(:,end));
                    points(:, end + 1) = new_point;
                end
                assignin('base', 'joint_point', points);
            end

        
            function results = calculate_tf_0_N(app, matrices)
                results = cell(1,size(matrices, 2));
                results{1} = matrices{1};
                assignin('base', 'denemee', results{end});
                for i = 2:(size(matrices, 2))
                    new_tf = results{i-1}*matrices{i};
                    assignin('base', 'new_tf', new_tf);
                    results{i} = new_tf;
                end
                assignin('base', 'results_matrices', results);
            end
        
            function results = jacobian_matrix_calculator(app, transformation_input)
                % Iteratif yöntemi denedim ama olmadı, beceremedim
                w_initial = [0;0;0];
                w_prev = w_initial;
                v_initial = [0;0;0];
                v_prev = v_initial;
                z = [0;0;1];
                results = [];
                variables = [];
                
                jacobian = zeros(6, 8);

                for i = 1:8
                    rotation_matrix = rotationmatrixcalculator(transformation_input{i});
                    p_matrix = pmatrixcalculator(transformation_input{i});
                
                    radio_button_name_R = sprintf('Button_R_%d', i-1);
                    radio_button_name_P = sprintf('Button_P_%d', i-1);
                    radio_button_name_N = sprintf('Button_N_%d', i-1);
                
                    if app.(radio_button_name_R).Value
                        disp("Dönel Eklem Seçildi")
                        theta_dot_name = ['theta_', num2str(i), '_dot'];
                        w_new = (rotation_matrix' * w_prev) + sym(theta_dot_name)*z;
                        assignin('base', 'rotasyonel_eklem_w', w_new);
                        v_new = rotation_matrix' * (v_prev + (w_prev .* p_matrix));
                        % variables(end + 1) = sym(theta_dot_name);
                        assignin('base', 'rotasyonel_eklem_v', v_new);
                        
     
                    elseif app.(radio_button_name_P).Value
                        disp("Prizmatik Eklem Seçildi")
                        d_dot_name = ['d_', num2str(i), '_dot'];
                        w_new = [0; 0; 0];
                        v_new = rotation_matrix' * (v_prev + (w_prev .* p_matrix)) + sym(d_dot_name)*z;
                        % variables(end + 1) = sym(d_dot_name);
                        assignin('base', 'prizmatik_eklem_v', v_new);
                        assignin('base', 'prizmatik_eklem_w', w_new);
                
                    elseif app.(radio_button_name_N).Value
                        disp("None Eklem Seçildi")
                        v_new = [0; 0; 0];
                        w_new = w_prev;
                        assignin('base', 'none_eklem_v', v_new);
                        assignin('base', 'none_eklem_w', w_new);
                
                    end
                    v_prev = v_new;
                    w_prev = w_new;

                    jacobian(1,i) = v_new(1,1);
                    jacobian(2,i) = v_new(2,1);
                    jacobian(3,i) = v_new(3,1);

                    jacobian(4,i) = w_new(1,1);
                    jacobian(5,i) = w_new(2,1);
                    jacobian(6,i) = w_new(3,1);
                end

            end
        
            function jacobian = jacobian_with_vectoral(app, transformation_matrices)
                % Iteratif yöntemi beceremeyince vektörel metodu kullandım
                jacobian = zeros(6, 8);
                angular_velocity_prev = [0;0;0];
                vectoral_velocity_prev = [0;0;0];
                tm_end_effector = transformation_matrices{end};
                p_end_effector = pmatrixcalculator(tm_end_effector);
                disp("P end Effector")
                disp(p_end_effector)
                for i = 1:8
                    radio_button_name_R = sprintf('Button_R_%d', i-1);
                    radio_button_name_P = sprintf('Button_P_%d', i-1);
                    radio_button_name_N = sprintf('Button_N_%d', i-1);

                    tm_current = transformation_matrices{i};
                    disp("Vektörel Tm Current")
                    tm_current
                    rotation_m = rotationmatrixcalculator(tm_current);
                    disp("Vektörel Rotasyon Matrixi")
                    rotation_m
                    z = rotation_m * [0;0;1];
                    disp("Vektörel Z")
                    z
                    p = pmatrixcalculator(tm_current);
                    disp("Vektörel P Current")
                    p
                    if app.(radio_button_name_R).Value
                        vectoral_velocity = cross(z, (p_end_effector - p));
                        angular_velocity = z;
                    elseif app.(radio_button_name_P).Value
                        vectoral_velocity = cross(z, (p_end_effector - p));
                        angular_velocity = [0;0;0];
                    elseif app.(radio_button_name_N).Value
                        vectoral_velocity = [0;0;0];
                        angular_velocity = angular_velocity_prev;
                    end
                    
                    disp("Vectoral Velocity")
                    disp(vectoral_velocity)
                
                    
                    disp("Angular Velocity")
                    disp(angular_velocity)

                    jacobian(1,i) = vectoral_velocity(1,1);
                    jacobian(2,i) = vectoral_velocity(2,1);
                    jacobian(3,i) = vectoral_velocity(3,1);
                    
                    jacobian(4,i) = angular_velocity(1,1);
                    jacobian(5,i) = angular_velocity(2,1);
                    jacobian(6,i) = angular_velocity(3,1);
                    
                    angular_velocity_prev = angular_velocity;
                    vectoral_velocity_prev = vectoral_velocity_prev;
                end
                assignin('base', 'jacobian_vectoral', jacobian);
            end
        
            function jacobian_to_tftab(app, jacobian)
                for i = 1:6
                    for j = 1:8
                        text_box = sprintf('Jacobian_%d%d', i, j);
                        app.(text_box).Value = num2str(jacobian(i,j));
                    end
                end
            
        end
        
        function draw_hyundai(app, points)

                fig = app.UIFigure;
                fig.Name = 'Matlab UI Örneği';
  
                ax  = app.UIAxes_hy;

                plot3(ax, points(1, 1), points(2, 1), points(3, 1), 'o-');
                hold(ax, 'on');

                for i = 2:size(points, 2)
                    radio_button_name_R = sprintf('Button_R_%d', i);
                    radio_button_name_P = sprintf('Button_P_%d', i);
                    radio_button_name_N = sprintf('Button_N_%d', i);
                
                    if radio_button_name_R
                        plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'ro-');
                    elseif radio_button_name_P
                        plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'ro-');
                    elseif radio_button_name_N
                        plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'go-');
                    end
                end
                hold(ax, 'off'); % 'hold off' ile çizim modu kapatılır

                grid(app.UIAxes_hy, 'on');
                xlabel(ax, 'X Ekseni');
                ylabel(ax, 'Y Ekseni');
                zlabel(ax, 'Z Ekseni');
                title(ax, 'Hyundai YL012');
            end
        
            function new_position_last_to_first = fabrik_forward(app, link_lengths, points, desired_point, active_joint_count)
            % İçine aldığı points'leri tersten sıralayarak dışarı verir -
            % yeni konumlarına göre
            p_desired = desired_point;
            new_position_last_to_first = [];
            for i = active_joint_count:-1:1
               disp("Point")
               points(1:3,i)
               disp("Unit Vector")
               unit_vector = (points(1:3,i)-p_desired)/(norm((points(1:3,i)-p_desired)))
               disp("Link Len")
               link_lengths(i)
               p_i_dot = p_desired + (link_lengths(i) * (unit_vector));
               disp("p_i_dot")
               p_i_dot
               p_desired = p_i_dot;
               new_position_last_to_first = [new_position_last_to_first, p_i_dot];
            end
            disp("Results Forward")
            new_position_last_to_first
        end
        
        function new_position_first_to_last = fabrik_backward(app, link_lengths, points, desired_point, active_joint_count)
            % Ters olarak aldığı pointsleri tekrar ters olarak dışarı
            % verir. Tersin tersi düz olur ve kullanılabilir.
            p_desired = desired_point;
            new_position_first_to_last = [];
            new_position_first_to_last = [new_position_first_to_last, [0;0;0]];
            for i = active_joint_count-1:-1:1
               disp("Point")
               points(1:3,i)
               disp("Unit Vector")
               unit_vector = (points(1:3,i)-p_desired)/norm((points(1:3,i)-p_desired))
               disp("Link Len")
               link_lengths(i)
               p_i_dot = p_desired + link_lengths(i) * (unit_vector);
               disp("p_i_dot")
               p_i_dot
               p_desired = p_i_dot;
               new_position_first_to_last = [new_position_first_to_last, p_i_dot];
            end
            disp("Results Backward")
            new_position_first_to_last
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: HesaplaButton
        function HesaplaButtonPushed(app, event)
            input_dh = [];
            for i = 0:7
                alpha_name = sprintf('EditField_%dalpha', i);
                a_name = sprintf('EditField_%da', i);
                d_name = sprintf('EditField_%dd', i);
                theta_name = sprintf('EditField_%dtheta', i);
                radio_button_name_R = sprintf('Button_R_%d', i);
                radio_button_name_P = sprintf('Button_P_%d', i);
                slider_name = sprintf('SliderVariable_%d', i);
                alpha = app.(alpha_name).Value;
                a = app.(a_name).Value;
                d = app.(d_name).Value;
                theta = app.(theta_name).Value;
                if app.(radio_button_name_R).Value
                    theta = theta + app.(slider_name).Value;
                elseif app.(radio_button_name_P).Value
                    d = d + app.(slider_name).Value;
                end
                input_dh(end + 1, :) = [alpha,a,d,theta];
            end
            assignin('base', 'Input_dh', input_dh);
            matrices = transformationmatrixcalculator(input_dh);
            assignin('base', 'matrices', matrices);
            tf_to_tftab(app, matrices);
            calculate_total_tf(app, matrices);
            calculated_tf_0_N = calculate_tf_0_N(app, matrices); % T0_1 T1_2 formatını T0_1 T0_2 formatına cevirir
            assignin('base', 'matrices0_n', calculated_tf_0_N); 
            points_calculated = calculate_joint_points(app, calculated_tf_0_N, [0;0;0;1]); % Çizdirmek için konumları bulur
            draw_graphic(app, points_calculated);
            jacobian = jacobian_with_vectoral(app, calculated_tf_0_N);
            jacobian_to_tftab(app, jacobian);
            link_len = computeLinkLengths(points_calculated);
            assignin('base', 'link_len', link_len);
        end

        % Value changed function: SliderVariable_4
        function SliderVariable_4ValueChanged(app, event)
            value = app.SliderVariable_4.Value;
            app.EditField_JV_4.Value = value;
        end

        % Value changed function: EditField_JV_4
        function EditField_JV_4ValueChanged(app, event)
            value = app.EditField_JV_4.Value;
            app.SliderVariable_4.Value = value;
        end

        % Value changed function: SliderVariable_0
        function SliderVariable_0ValueChanged(app, event)
            value = app.SliderVariable_0.Value;
            app.EditField_JV_0.Value = value;
        end

        % Value changed function: SliderVariable_1
        function SliderVariable_1ValueChanged(app, event)
            value = app.SliderVariable_1.Value;
            app.EditField_JV_1.Value = value;
        end

        % Value changed function: SliderVariable_2
        function SliderVariable_2ValueChanged(app, event)
            value = app.SliderVariable_2.Value;
            app.EditField_JV_2.Value = value;
        end

        % Value changed function: SliderVariable_3
        function SliderVariable_3ValueChanged(app, event)
            value = app.SliderVariable_3.Value;
            app.EditField_JV_3.Value = value;
        end

        % Value changed function: SliderVariable_5
        function SliderVariable_5ValueChanged(app, event)
            value = app.SliderVariable_5.Value;
            app.EditField_JV_5.Value = value;
        end

        % Value changed function: SliderVariable_6
        function SliderVariable_6ValueChanged(app, event)
            value = app.SliderVariable_6.Value;
            app.EditField_JV_6.Value = value;
        end

        % Value changed function: SliderVariable_7
        function SliderVariable_7ValueChanged(app, event)
            value = app.SliderVariable_7.Value;
            app.EditField_JV_7.Value = value;
        end

        % Value changed function: EditField_JV_0
        function EditField_JV_0ValueChanged(app, event)
            value = app.EditField_JV_0.Value;
            app.SliderVariable_0.Value = value;
        end

        % Value changed function: EditField_JV_1
        function EditField_JV_1ValueChanged(app, event)
            value = app.EditField_JV_1.Value;
            app.SliderVariable_1.Value = value;
        end

        % Value changed function: EditField_JV_2
        function EditField_JV_2ValueChanged(app, event)
            value = app.EditField_JV_2.Value;
            app.SliderVariable_2.Value = value;
        end

        % Value changed function: EditField_JV_3
        function EditField_JV_3ValueChanged(app, event)
            value = app.EditField_JV_3.Value;
            app.SliderVariable_3.Value = value;
        end

        % Value changed function: EditField_JV_5
        function EditField_JV_5ValueChanged(app, event)
            value = app.EditField_JV_5.Value;
            app.SliderVariable_5.Value = value;
        end

        % Value changed function: EditField_JV_6
        function EditField_JV_6ValueChanged(app, event)
            value = app.EditField_JV_6.Value;
            app.SliderVariable_6.Value = value;
        end

        % Value changed function: EditField_JV_7
        function EditField_JV_7ValueChanged(app, event)
            value = app.EditField_JV_7.Value;
            app.SliderVariable_7.Value = value;
        end

        % Value changed function: SliderVariable_hy_0
        function SliderVariable_hy_0ValueChanged(app, event)
            value = app.SliderVariable_hy_0.Value;
            app.EditField_JV_hy.Value = value;
        end

        % Value changed function: SliderVariable_hy_1
        function SliderVariable_hy_1ValueChanged(app, event)
            value = app.SliderVariable_hy_1.Value;
            app.EditField_JV_hy_1.Value = value;
        end

        % Value changed function: SliderVariable_hy_2
        function SliderVariable_hy_2ValueChanged(app, event)
            value = app.SliderVariable_hy_2.Value;
            app.EditField_JV_hy_2.Value = value;
        end

        % Value changed function: SliderVariable_hy_3
        function SliderVariable_hy_3ValueChanged(app, event)
            value = app.SliderVariable_hy_3.Value;
            app.EditField_JV_hy_3.Value = value;
        end

        % Value changed function: SliderVariable_hy_4
        function SliderVariable_hy_4ValueChanged(app, event)
            value = app.SliderVariable_hy_4.Value;
            app.EditField_JV_hy_4.Value = value;
        end

        % Value changed function: SliderVariable_hy_5
        function SliderVariable_hy_5ValueChanged(app, event)
            value = app.SliderVariable_hy_5.Value;
            app.EditField_JV_hy_5.Value = value;
        end

        % Value changed function: SliderVariable_hy_6
        function SliderVariable_hy_6ValueChanged(app, event)
            value = app.SliderVariable_hy_6.Value;
            app.EditField_JV_hy_6.Value = value;
        end

        % Value changed function: SliderVariable_hy_7
        function SliderVariable_hy_7ValueChanged(app, event)
            value = app.SliderVariable_hy_7.Value;
            app.EditField_JV_hy_7.Value = value;
        end

        % Value changed function: EditField_JV_hy
        function EditField_JV_hyValueChanged(app, event)
            value = app.EditField_JV_hy.Value;
            app.SliderVariable_hy_0.Value = value;
        end

        % Value changed function: EditField_JV_hy_1
        function EditField_JV_hy_1ValueChanged(app, event)
            value = app.EditField_JV_hy_1.Value;
            app.SliderVariable_hy_1.Value = value;
        end

        % Value changed function: EditField_JV_hy_2
        function EditField_JV_hy_2ValueChanged(app, event)
            value = app.EditField_JV_hy_2.Value;
            app.SliderVariable_hy_2.Value = value;
        end

        % Value changed function: EditField_JV_hy_3
        function EditField_JV_hy_3ValueChanged(app, event)
            value = app.EditField_JV_hy_3.Value;
            app.SliderVariable_hy_3.Value = value;
        end

        % Value changed function: EditField_JV_hy_4
        function EditField_JV_hy_4ValueChanged(app, event)
            value = app.EditField_JV_hy_4.Value;
            app.SliderVariable_hy_4.Value = value;
        end

        % Value changed function: EditField_JV_hy_5
        function EditField_JV_hy_5ValueChanged(app, event)
            value = app.EditField_JV_hy_5.Value;
            app.SliderVariable_hy_5.Value = value;
        end

        % Value changed function: EditField_JV_hy_6
        function EditField_JV_hy_6ValueChanged(app, event)
            value = app.EditField_JV_hy_6.Value;
            app.SliderVariable_hy_6.Value = value;
        end

        % Value changed function: EditField_JV_hy_7
        function EditField_JV_hy_7ValueChanged(app, event)
            value = app.EditField_JV_hy_7.Value;
            app.SliderVariable_hy_7.Value = value;
        end

        % Button pushed function: HesaplaButton_Hyundai
        function HesaplaButton_HyundaiPushed(app, event)
            input_dh = [];
            for i = 0:7
                alpha_name = sprintf('EditField_%dalpha_hy', i);
                a_name = sprintf('EditField_%da_hy', i);
                d_name = sprintf('EditField_%dd_hy', i);
                theta_name = sprintf('EditField_%dtheta_hy', i);
                slider_name = sprintf('SliderVariable_hy_%d', i);
                alpha = app.(alpha_name).Value;
                a = app.(a_name).Value;
                d = app.(d_name).Value;
                theta = app.(theta_name).Value;
                theta = theta + app.(slider_name).Value;
                input_dh(end + 1, :) = [alpha,a,d,theta];
            end
            assignin('base', 'Input_dh', input_dh);
            matrices = transformationmatrixcalculator(input_dh);
            assignin('base', 'matrices', matrices);
            tf_to_tftab(app, matrices);
            calculate_total_tf(app, matrices);
            calculated_tf_0_N = calculate_tf_0_N(app, matrices);
            assignin('base', 'matrices0_n', calculated_tf_0_N);
            points_calculated = calculate_joint_points(app, calculated_tf_0_N, [0;0;0;1]);
            draw_hyundai(app, points_calculated);
            % jacobian_matrix_calculator(app, matrices);
            jacobian = jacobian_with_vectoral(app, calculated_tf_0_N);
            jacobian_to_tftab(app, jacobian);
        end

        % Button pushed function: TersKinematikHesaplaButton
        function TersKinematikHesaplaButtonPushed(app, event)
            input_dh = [];
            for i = 0:7
                alpha_name = sprintf('EditField_%dalpha', i);
                a_name = sprintf('EditField_%da', i);
                d_name = sprintf('EditField_%dd', i);
                theta_name = sprintf('EditField_%dtheta', i);
                radio_button_name_R = sprintf('Button_R_%d', i);
                radio_button_name_P = sprintf('Button_P_%d', i);
                slider_name = sprintf('SliderVariable_%d', i);
                alpha = app.(alpha_name).Value;
                a = app.(a_name).Value;
                d = app.(d_name).Value;
                theta = app.(theta_name).Value;
                if app.(radio_button_name_R).Value
                    theta = theta + app.(slider_name).Value;
                elseif app.(radio_button_name_P).Value
                    d = d + app.(slider_name).Value;
                end
                input_dh(end + 1, :) = [alpha,a,d,theta];
            end
            assignin('base', 'Input_dh', input_dh);
            matrices = transformationmatrixcalculator(input_dh);
            assignin('base', 'matrices', matrices);
            tf_to_tftab(app, matrices);
            calculate_total_tf(app, matrices);
            calculated_tf_0_N = calculate_tf_0_N(app, matrices);
            assignin('base', 'matrices0_n', calculated_tf_0_N);
            points_calculated = calculate_joint_points(app, calculated_tf_0_N, [0;0;0;1]);
            link_len = computeLinkLengths(points_calculated);
            assignin('base', 'link_len', link_len);
            max_iter = app.EditField_IK_max_iter.Value;
            iter = 0;
            error = 100;
            error_th = 0.5;
            active_joint_count = app.EditField_IK_active_joint.Value;
            desired_point = [app.EditField_IK_x.Value;app.EditField_IK_y.Value;app.EditField_IK_z.Value];
            output_backward = points_calculated;
            link_len = link_len(1:active_joint_count);
            while (iter < max_iter && error > error_th)
                output_forward = fabrik_forward(app, link_len, output_backward, desired_point, active_joint_count);
                output_backward = fabrik_backward(app, flip(link_len), output_forward, [0;0;0], active_joint_count);
                iter = iter + 1;
                error = norm(output_backward(:,end) - desired_point);
                disp("Error ------ Error Fabrik")
                desired_point
                error
            end    
            draw_graphic(app, output_backward)
        end

        % Value changing function: SliderVariable_hy_0
        function SliderVariable_hy_0ValueChanging(app, event)
            theta_chc_0 = event.Value;  
        
           set_param('Assem5/Slider Gain', 'Gain', num2str(theta_chc_0))
        end

        % Button pushed function: SimlasyonButton
        function SimlasyonButtonPushed(app, event)
            path = ('C:\Users\Nezih\Desktop\YL\Assem5.slx');
            open_system(path)
        end

        % Value changing function: SliderVariable_hy_1
        function SliderVariable_hy_1ValueChanging(app, event)
            theta_chc_1 = event.Value;
        
           set_param('Assem5/Slider Gain1', 'Gain', num2str(theta_chc_1))
        end

        % Value changing function: SliderVariable_hy_2
        function SliderVariable_hy_2ValueChanging(app, event)
            theta_chc_2 = event.Value;  
        
           set_param('Assem5/Slider Gain2', 'Gain', num2str(theta_chc_2))
            
        end

        % Value changing function: SliderVariable_hy_3
        function SliderVariable_hy_3ValueChanging(app, event)
            theta_chc_3 = event.Value;  
        
           set_param('Assem5/Slider Gain3', 'Gain', num2str(theta_chc_3))
            
        end

        % Value changing function: SliderVariable_hy_4
        function SliderVariable_hy_4ValueChanging(app, event)
            theta_chc_4 = event.Value;  
        
           set_param('Assem5/Slider Gain4', 'Gain', num2str(theta_chc_4))
            
        end

        % Value changing function: SliderVariable_hy_5
        function SliderVariable_hy_5ValueChanging(app, event)
            theta_chc_5 = event.Value;  
        
           set_param('Assem5/Slider Gain5', 'Gain', num2str(theta_chc_5))
            
        end

        % Value changing function: SliderVariable_hy_6
        function SliderVariable_hy_6ValueChanging(app, event)
            theta_chc_6 = event.Value;  
        
           set_param('Assem5/Slider Gain6', 'Gain', num2str(theta_chc_6))
            
        end

        % Button pushed function: txtOlarakKaydetButton
        function txtOlarakKaydetButtonPushed(app, event)
            dosyaAdi = 'output.txt';

            dosya = fopen(dosyaAdi, 'w');
            matrices0_n = evalin('base', 'matrices0_n');
            for i = 1:numel(matrices0_n)
                fprintf(dosya, 'T0_%d', i);
                fprintf(dosya, '\n');
                fprintf(dosya, '%-15s', mat2str(matrices0_n{i}));
                
                if mod(i, 1) == 0
                    fprintf(dosya, '\n');
                end
            end

            fprintf(dosya, '\n');
            fprintf(dosya, '\n');
            fprintf(dosya, '\n');
            fprintf(dosya, '\n');

            matrices = evalin('base', 'matrices');
            for i = 1:numel(matrices)
                fprintf(dosya, 'T%d_%d', i-1, i);
                fprintf(dosya, '\n');
                fprintf(dosya, '%-15s', mat2str(matrices{i}));
                
                if mod(i, 1) == 0
                    fprintf(dosya, '\n');
                end
            end

            fprintf(dosya, '\n');
            fprintf(dosya, '\n');
            fprintf(dosya, '\n');
            fprintf(dosya, '\n');
            
            jacobian = evalin('base', 'jacobian_vectoral');
            fprintf(dosya, 'Jacobian', i-1, i);
            fprintf(dosya, '\n');
            fprintf(dosya, '%-15s', mat2str(jacobian));


            fclose(dosya);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GenelTab = uitab(app.TabGroup);
            app.GenelTab.Title = 'Genel';
            app.DHTablosuPanel = uipanel(app.GenelTab);
            app.DHTablosuPanel.Title = 'DH Tablosu';
            app.EditField_0alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_0a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_0d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_0theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_1theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_1d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_1a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_1alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_2theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_2d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_2a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_2alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_3theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_3d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_3a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_3alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_4theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_4d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_4a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_4alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_5theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_5d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_5a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_5alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_6theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_6d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_6a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_6alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_7theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_7d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_7a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_7alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EklemTipiLabel = uilabel(app.DHTablosuPanel);
            app.EklemTipiLabel.Text = 'Eklem Tipi';
            app.Label = uilabel(app.DHTablosuPanel);
            app.Label.Text = 'α';
            app.aLabel = uilabel(app.DHTablosuPanel);
            app.aLabel.Text = 'a';
            app.dLabel = uilabel(app.DHTablosuPanel);
            app.dLabel.Text = 'd';
            app.Label_2 = uilabel(app.DHTablosuPanel);
            app.Label_2.Text = 'θ';
            app.Label_3 = uilabel(app.DHTablosuPanel);
            app.Label_3.Text = '0-1';
            app.Label_4 = uilabel(app.DHTablosuPanel);
            app.Label_4.Text = '1-2';
            app.Label_5 = uilabel(app.DHTablosuPanel);
            app.Label_5.Text = '2-3';
            app.Label_6 = uilabel(app.DHTablosuPanel);
            app.Label_6.Text = '3-4';
            app.Label_7 = uilabel(app.DHTablosuPanel);
            app.Label_7.Text = '4-5';
            app.Label_8 = uilabel(app.DHTablosuPanel);
            app.Label_8.Text = '5-6';
            app.Label_9 = uilabel(app.DHTablosuPanel);
            app.Label_9.Text = '6-7';
            app.Label_10 = uilabel(app.DHTablosuPanel);
            app.Label_10.Text = '7-8';
            app.EklemlerLabel = uilabel(app.DHTablosuPanel);
            app.EklemlerLabel.Text = 'Eklemler';
            app.HesaplaButton = uibutton(app.DHTablosuPanel, 'push');
            app.HesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButtonPushed, true);
            app.HesaplaButton.Text = 'Hesapla';
            app.SliderVariable_0 = uislider(app.DHTablosuPanel);
            app.SliderVariable_0.Limits = [-360 360];
            app.SliderVariable_0.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_0ValueChanged, true);
            app.JointVariableLabel = uilabel(app.DHTablosuPanel);
            app.JointVariableLabel.Text = 'Eklem Değişkeni Değeri';
            app.SliderVariable_1 = uislider(app.DHTablosuPanel);
            app.SliderVariable_1.Limits = [-360 360];
            app.SliderVariable_1.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_1ValueChanged, true);
            app.SliderVariable_2 = uislider(app.DHTablosuPanel);
            app.SliderVariable_2.Limits = [-360 360];
            app.SliderVariable_2.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_2ValueChanged, true);
            app.SliderVariable_3 = uislider(app.DHTablosuPanel);
            app.SliderVariable_3.Limits = [-360 360];
            app.SliderVariable_3.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_3ValueChanged, true);
            app.SliderVariable_4 = uislider(app.DHTablosuPanel);
            app.SliderVariable_4.Limits = [-360 360];
            app.SliderVariable_4.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_4ValueChanged, true);
            app.SliderVariable_5 = uislider(app.DHTablosuPanel);
            app.SliderVariable_5.Limits = [-360 360];
            app.SliderVariable_5.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_5ValueChanged, true);
            app.SliderVariable_6 = uislider(app.DHTablosuPanel);
            app.SliderVariable_6.Limits = [-360 360];
            app.SliderVariable_6.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_6ValueChanged, true);
            app.SliderVariable_7 = uislider(app.DHTablosuPanel);
            app.SliderVariable_7.Limits = [-360 360];
            app.SliderVariable_7.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_7ValueChanged, true);
            app.ButtonGroup_0 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_0 = uiradiobutton(app.ButtonGroup_0);
            app.Button_R_0.Text = 'R';
            app.Button_R_0.Value = true;
            app.Button_P_0 = uiradiobutton(app.ButtonGroup_0);
            app.Button_P_0.Text = 'P';
            app.Button_N_0 = uiradiobutton(app.ButtonGroup_0);
            app.Button_N_0.Text = 'N';
            app.ButtonGroup_1 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_1 = uiradiobutton(app.ButtonGroup_1);
            app.Button_R_1.Text = 'R';
            app.Button_R_1.Value = true;
            app.Button_P_1 = uiradiobutton(app.ButtonGroup_1);
            app.Button_P_1.Text = 'P';
            app.Button_N_1 = uiradiobutton(app.ButtonGroup_1);
            app.Button_N_1.Text = 'N';
            app.ButtonGroup_2 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_R_2.Text = 'R';
            app.Button_R_2.Value = true;
            app.Button_P_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_P_2.Text = 'P';
            app.Button_N_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_N_2.Text = 'N';
            app.ButtonGroup_3 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_3 = uiradiobutton(app.ButtonGroup_3);
            app.Button_R_3.Text = 'R';
            app.Button_R_3.Value = true;
            app.Button_P_3 = uiradiobutton(app.ButtonGroup_3);
            app.Button_P_3.Text = 'P';
            app.Button_N_3 = uiradiobutton(app.ButtonGroup_3);
            app.Button_N_3.Text = 'N';
            app.ButtonGroup_4 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_4 = uiradiobutton(app.ButtonGroup_4);
            app.Button_R_4.Text = 'R';
            app.Button_R_4.Value = true;
            app.Button_P_4 = uiradiobutton(app.ButtonGroup_4);
            app.Button_P_4.Text = 'P';
            app.Button_N_4 = uiradiobutton(app.ButtonGroup_4);
            app.Button_N_4.Text = 'N';
            app.ButtonGroup_5 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_5 = uiradiobutton(app.ButtonGroup_5);
            app.Button_R_5.Text = 'R';
            app.Button_R_5.Value = true;
            app.Button_P_5 = uiradiobutton(app.ButtonGroup_5);
            app.Button_P_5.Text = 'P';
            app.Button_N_5 = uiradiobutton(app.ButtonGroup_5);
            app.Button_N_5.Text = 'N';
            app.ButtonGroup_6 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_6 = uiradiobutton(app.ButtonGroup_6);
            app.Button_R_6.Text = 'R';
            app.Button_R_6.Value = true;
            app.Button_P_6 = uiradiobutton(app.ButtonGroup_6);
            app.Button_P_6.Text = 'P';
            app.Button_N_6 = uiradiobutton(app.ButtonGroup_6);
            app.Button_N_6.Text = 'N';
            app.ButtonGroup_7 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_7 = uiradiobutton(app.ButtonGroup_7);
            app.Button_R_7.Text = 'R';
            app.Button_R_7.Value = true;
            app.Button_P_7 = uiradiobutton(app.ButtonGroup_7);
            app.Button_P_7.Text = 'P';
            app.Button_N_7 = uiradiobutton(app.ButtonGroup_7);
            app.Button_N_7.Text = 'N';
            app.EditField_JV_0 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_0.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_0ValueChanged, true);
            app.EditField_JV_1 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_1.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_1ValueChanged, true);
            app.EditField_JV_2 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_2.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_2ValueChanged, true);
            app.EditField_JV_3 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_3.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_3ValueChanged, true);
            app.EditField_JV_4 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_4.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_4ValueChanged, true);
            app.EditField_JV_5 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_5.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_5ValueChanged, true);
            app.EditField_JV_6 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_6.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_6ValueChanged, true);
            app.EditField_JV_7 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_7.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_7ValueChanged, true);
            app.TersKinematikHesaplaButton = uibutton(app.DHTablosuPanel, 'push');
            app.TersKinematikHesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @TersKinematikHesaplaButtonPushed, true);
            app.TersKinematikHesaplaButton.Text = 'Ters Kinematik Hesapla';
            app.EditField_IK_x = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_IK_y = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_IK_z = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_IK_max_iter = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_IK_active_joint = uieditfield(app.DHTablosuPanel, 'numeric');
            app.IKXLabel = uilabel(app.DHTablosuPanel);
            app.IKXLabel.Text = 'IK: X';
            app.IKYLabel = uilabel(app.DHTablosuPanel);
            app.IKYLabel.Text = 'IK: Y';
            app.IKZLabel = uilabel(app.DHTablosuPanel);
            app.IKZLabel.Text = 'IK: Z';
            app.IKMaksimumterLabel = uilabel(app.DHTablosuPanel);
            app.IKMaksimumterLabel.Text = 'IK: Maksimum İter';
            app.IKAktifEklemSaysLabel = uilabel(app.DHTablosuPanel);
            app.IKAktifEklemSaysLabel.Text = 'IK: Aktif Eklem Sayısı';
            app.IKRollLabel = uilabel(app.DHTablosuPanel);
            app.IKRollLabel.Text = 'IK: Roll';
            app.EditField_IK_roll = uieditfield(app.DHTablosuPanel, 'numeric');
            app.IKPitchLabel = uilabel(app.DHTablosuPanel);
            app.IKPitchLabel.Text = 'IK: Pitch';
            app.EditField_IK_pitch = uieditfield(app.DHTablosuPanel, 'numeric');
            app.IKYawLabel = uilabel(app.DHTablosuPanel);
            app.IKYawLabel.Text = 'IK: Yaw';
            app.EditField_IK_yaw = uieditfield(app.DHTablosuPanel, 'numeric');
            app.Panel = uipanel(app.GenelTab);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HyundaiYL012Tab = uitab(app.TabGroup);
            app.HyundaiYL012Tab.Title = 'Hyundai YL012';
            app.DHTablosuHyundaiYL012Panel = uipanel(app.HyundaiYL012Tab);
            app.DHTablosuHyundaiYL012Panel.Title = 'DH Tablosu - Hyundai YL012';
            app.EditField_0alpha_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_0alpha_hy.Editable = 'off';
            app.EditField_0a_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_0a_hy.Editable = 'off';
            app.EditField_0d_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_0d_hy.Editable = 'off';
            app.EditField_0d_hy.Value = 379;
            app.EditField_0theta_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_0theta_hy.Editable = 'off';
            app.EditField_1theta_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_1theta_hy.Editable = 'off';
            app.EditField_1theta_hy.Value = 90;
            app.EditField_1d_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_1d_hy.Editable = 'off';
            app.EditField_1a_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_1a_hy.Editable = 'off';
            app.EditField_1a_hy.Value = 384;
            app.EditField_1alpha_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_1alpha_hy.Editable = 'off';
            app.EditField_2theta_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_2theta_hy.Editable = 'off';
            app.EditField_2theta_hy.Value = -90;
            app.EditField_2d_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_2d_hy.Editable = 'off';
            app.EditField_2d_hy.Value = 674;
            app.EditField_2a_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_2a_hy.Editable = 'off';
            app.EditField_2alpha_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_2alpha_hy.Editable = 'off';
            app.EditField_3theta_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_3theta_hy.Editable = 'off';
            app.EditField_3theta_hy.Value = 90;
            app.EditField_3d_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_3d_hy.Editable = 'off';
            app.EditField_3a_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_3a_hy.Editable = 'off';
            app.EditField_3a_hy.Value = 300;
            app.EditField_3alpha_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_3alpha_hy.Editable = 'off';
            app.EditField_3alpha_hy.Value = 90;
            app.EditField_4theta_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_4theta_hy.Editable = 'off';
            app.EditField_4theta_hy.Value = -90;
            app.EditField_4d_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_4d_hy.Editable = 'off';
            app.EditField_4d_hy.Value = 375;
            app.EditField_4a_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_4a_hy.Editable = 'off';
            app.EditField_4a_hy.Value = -171;
            app.EditField_4alpha_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_4alpha_hy.Editable = 'off';
            app.EditField_5theta_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_5theta_hy.Editable = 'off';
            app.EditField_5d_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_5d_hy.Editable = 'off';
            app.EditField_5a_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_5a_hy.Editable = 'off';
            app.EditField_5alpha_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_5alpha_hy.Editable = 'off';
            app.EditField_5alpha_hy.Value = 45;
            app.EditField_6theta_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_6theta_hy.Editable = 'off';
            app.EditField_6d_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_6d_hy.Editable = 'off';
            app.EditField_6d_hy.Value = 91;
            app.EditField_6a_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_6a_hy.Editable = 'off';
            app.EditField_6alpha_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_6alpha_hy.Editable = 'off';
            app.EditField_6alpha_hy.Value = -45;
            app.EditField_7theta_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_7theta_hy.Editable = 'off';
            app.EditField_7d_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_7d_hy.Editable = 'off';
            app.EditField_7d_hy.Value = 165;
            app.EditField_7a_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_7a_hy.Editable = 'off';
            app.EditField_7alpha_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_7alpha_hy.Editable = 'off';
            app.Label_11 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_11.Text = 'α';
            app.aLabel_2 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.aLabel_2.Text = 'a';
            app.dLabel_2 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.dLabel_2.Text = 'd';
            app.Label_12 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_12.Text = 'θ';
            app.Label_13 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_13.Text = '0-1';
            app.Label_14 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_14.Text = '1-2';
            app.Label_15 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_15.Text = '2-3';
            app.Label_16 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_16.Text = '3-4';
            app.Label_17 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_17.Text = '4-5';
            app.Label_18 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_18.Text = '5-6';
            app.Label_19 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_19.Text = '6-7';
            app.Label_20 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.Label_20.Text = '7-8';
            app.EklemlerLabel_2 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.EklemlerLabel_2.Text = 'Eklemler';
            app.HesaplaButton_Hyundai = uibutton(app.DHTablosuHyundaiYL012Panel, 'push');
            app.HesaplaButton_Hyundai.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButton_HyundaiPushed, true);
            app.HesaplaButton_Hyundai.Text = 'Hesapla';
            app.SliderVariable_hy_0 = uislider(app.DHTablosuHyundaiYL012Panel);
            app.SliderVariable_hy_0.Limits = [-360 360];
            app.SliderVariable_hy_0.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_hy_0ValueChanged, true);
            app.SliderVariable_hy_0.ValueChangingFcn = createCallbackFcn(app, @SliderVariable_hy_0ValueChanging, true);
            app.JointVariableLabel_2 = uilabel(app.DHTablosuHyundaiYL012Panel);
            app.JointVariableLabel_2.Text = 'Eklem Değişkeni Değeri';
            app.SliderVariable_hy_1 = uislider(app.DHTablosuHyundaiYL012Panel);
            app.SliderVariable_hy_1.Limits = [-360 360];
            app.SliderVariable_hy_1.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_hy_1ValueChanged, true);
            app.SliderVariable_hy_1.ValueChangingFcn = createCallbackFcn(app, @SliderVariable_hy_1ValueChanging, true);
            app.SliderVariable_hy_2 = uislider(app.DHTablosuHyundaiYL012Panel);
            app.SliderVariable_hy_2.Limits = [-360 360];
            app.SliderVariable_hy_2.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_hy_2ValueChanged, true);
            app.SliderVariable_hy_2.ValueChangingFcn = createCallbackFcn(app, @SliderVariable_hy_2ValueChanging, true);
            app.SliderVariable_hy_3 = uislider(app.DHTablosuHyundaiYL012Panel);
            app.SliderVariable_hy_3.Limits = [-360 360];
            app.SliderVariable_hy_3.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_hy_3ValueChanged, true);
            app.SliderVariable_hy_3.ValueChangingFcn = createCallbackFcn(app, @SliderVariable_hy_3ValueChanging, true);
            app.SliderVariable_hy_4 = uislider(app.DHTablosuHyundaiYL012Panel);
            app.SliderVariable_hy_4.Limits = [-360 360];
            app.SliderVariable_hy_4.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_hy_4ValueChanged, true);
            app.SliderVariable_hy_4.ValueChangingFcn = createCallbackFcn(app, @SliderVariable_hy_4ValueChanging, true);
            app.SliderVariable_hy_5 = uislider(app.DHTablosuHyundaiYL012Panel);
            app.SliderVariable_hy_5.Limits = [-360 360];
            app.SliderVariable_hy_5.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_hy_5ValueChanged, true);
            app.SliderVariable_hy_5.ValueChangingFcn = createCallbackFcn(app, @SliderVariable_hy_5ValueChanging, true);
            app.SliderVariable_hy_6 = uislider(app.DHTablosuHyundaiYL012Panel);
            app.SliderVariable_hy_6.Limits = [-360 360];
            app.SliderVariable_hy_6.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_hy_6ValueChanged, true);
            app.SliderVariable_hy_6.ValueChangingFcn = createCallbackFcn(app, @SliderVariable_hy_6ValueChanging, true);
            app.SliderVariable_hy_7 = uislider(app.DHTablosuHyundaiYL012Panel);
            app.SliderVariable_hy_7.Limits = [-360 360];
            app.SliderVariable_hy_7.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_hy_7ValueChanged, true);
            app.EditField_JV_hy = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_JV_hy.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_hyValueChanged, true);
            app.EditField_JV_hy_1 = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_JV_hy_1.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_hy_1ValueChanged, true);
            app.EditField_JV_hy_2 = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_JV_hy_2.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_hy_2ValueChanged, true);
            app.EditField_JV_hy_3 = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_JV_hy_3.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_hy_3ValueChanged, true);
            app.EditField_JV_hy_4 = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_JV_hy_4.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_hy_4ValueChanged, true);
            app.EditField_JV_hy_5 = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_JV_hy_5.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_hy_5ValueChanged, true);
            app.EditField_JV_hy_6 = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_JV_hy_6.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_hy_6ValueChanged, true);
            app.EditField_JV_hy_7 = uieditfield(app.DHTablosuHyundaiYL012Panel, 'numeric');
            app.EditField_JV_hy_7.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_hy_7ValueChanged, true);
            app.SimlasyonButton = uibutton(app.DHTablosuHyundaiYL012Panel, 'push');
            app.SimlasyonButton.ButtonPushedFcn = createCallbackFcn(app, @SimlasyonButtonPushed, true);
            app.SimlasyonButton.Text = 'Simülasyon';
            app.Panel_2 = uipanel(app.HyundaiYL012Tab);
            app.Panel_2.Title = 'Panel';
            app.UIAxes_hy = uiaxes(app.Panel_2);
            title(app.UIAxes_hy, 'Title')
            xlabel(app.UIAxes_hy, 'X')
            ylabel(app.UIAxes_hy, 'Y')
            zlabel(app.UIAxes_hy, 'Z')
            app.TransformasyonMatrisleriTab = uitab(app.TabGroup);
            app.TransformasyonMatrisleriTab.Title = 'Transformasyon Matrisleri';
            app.T01Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T01Panel.Title = 'T 0 - 1';
            app.TM0_00 = uieditfield(app.T01Panel, 'text');
            app.TM0_00.Editable = 'off';
            app.TM0_01 = uieditfield(app.T01Panel, 'text');
            app.TM0_01.Editable = 'off';
            app.TM0_02 = uieditfield(app.T01Panel, 'text');
            app.TM0_02.Editable = 'off';
            app.TM0_03 = uieditfield(app.T01Panel, 'text');
            app.TM0_03.Editable = 'off';
            app.TM0_13 = uieditfield(app.T01Panel, 'text');
            app.TM0_13.Editable = 'off';
            app.TM0_12 = uieditfield(app.T01Panel, 'text');
            app.TM0_12.Editable = 'off';
            app.TM0_11 = uieditfield(app.T01Panel, 'text');
            app.TM0_11.Editable = 'off';
            app.TM0_10 = uieditfield(app.T01Panel, 'text');
            app.TM0_10.Editable = 'off';
            app.TM0_23 = uieditfield(app.T01Panel, 'text');
            app.TM0_23.Editable = 'off';
            app.TM0_22 = uieditfield(app.T01Panel, 'text');
            app.TM0_22.Editable = 'off';
            app.TM0_21 = uieditfield(app.T01Panel, 'text');
            app.TM0_21.Editable = 'off';
            app.TM0_20 = uieditfield(app.T01Panel, 'text');
            app.TM0_20.Editable = 'off';
            app.TM0_33 = uieditfield(app.T01Panel, 'text');
            app.TM0_33.Editable = 'off';
            app.TM0_32 = uieditfield(app.T01Panel, 'text');
            app.TM0_32.Editable = 'off';
            app.TM0_31 = uieditfield(app.T01Panel, 'text');
            app.TM0_31.Editable = 'off';
            app.TM0_30 = uieditfield(app.T01Panel, 'text');
            app.TM0_30.Editable = 'off';
            app.T12Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T12Panel.Title = 'T 1-2';
            app.TM1_00 = uieditfield(app.T12Panel, 'text');
            app.TM1_00.Editable = 'off';
            app.TM1_01 = uieditfield(app.T12Panel, 'text');
            app.TM1_01.Editable = 'off';
            app.TM1_02 = uieditfield(app.T12Panel, 'text');
            app.TM1_02.Editable = 'off';
            app.TM1_03 = uieditfield(app.T12Panel, 'text');
            app.TM1_03.Editable = 'off';
            app.TM1_13 = uieditfield(app.T12Panel, 'text');
            app.TM1_13.Editable = 'off';
            app.TM1_12 = uieditfield(app.T12Panel, 'text');
            app.TM1_12.Editable = 'off';
            app.TM1_11 = uieditfield(app.T12Panel, 'text');
            app.TM1_11.Editable = 'off';
            app.TM1_10 = uieditfield(app.T12Panel, 'text');
            app.TM1_10.Editable = 'off';
            app.TM1_23 = uieditfield(app.T12Panel, 'text');
            app.TM1_23.Editable = 'off';
            app.TM1_22 = uieditfield(app.T12Panel, 'text');
            app.TM1_22.Editable = 'off';
            app.TM1_21 = uieditfield(app.T12Panel, 'text');
            app.TM1_21.Editable = 'off';
            app.TM1_20 = uieditfield(app.T12Panel, 'text');
            app.TM1_20.Editable = 'off';
            app.TM1_33 = uieditfield(app.T12Panel, 'text');
            app.TM1_33.Editable = 'off';
            app.TM1_32 = uieditfield(app.T12Panel, 'text');
            app.TM1_32.Editable = 'off';
            app.TM1_31 = uieditfield(app.T12Panel, 'text');
            app.TM1_31.Editable = 'off';
            app.TM1_30 = uieditfield(app.T12Panel, 'text');
            app.TM1_30.Editable = 'off';
            app.T23Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T23Panel.Title = 'T 2-3';
            app.TM2_00 = uieditfield(app.T23Panel, 'text');
            app.TM2_00.Editable = 'off';
            app.TM2_01 = uieditfield(app.T23Panel, 'text');
            app.TM2_01.Editable = 'off';
            app.TM2_02 = uieditfield(app.T23Panel, 'text');
            app.TM2_02.Editable = 'off';
            app.TM2_03 = uieditfield(app.T23Panel, 'text');
            app.TM2_03.Editable = 'off';
            app.TM2_13 = uieditfield(app.T23Panel, 'text');
            app.TM2_13.Editable = 'off';
            app.TM2_12 = uieditfield(app.T23Panel, 'text');
            app.TM2_12.Editable = 'off';
            app.TM2_11 = uieditfield(app.T23Panel, 'text');
            app.TM2_11.Editable = 'off';
            app.TM2_10 = uieditfield(app.T23Panel, 'text');
            app.TM2_10.Editable = 'off';
            app.TM2_23 = uieditfield(app.T23Panel, 'text');
            app.TM2_23.Editable = 'off';
            app.TM2_22 = uieditfield(app.T23Panel, 'text');
            app.TM2_22.Editable = 'off';
            app.TM2_21 = uieditfield(app.T23Panel, 'text');
            app.TM2_21.Editable = 'off';
            app.TM2_20 = uieditfield(app.T23Panel, 'text');
            app.TM2_20.Editable = 'off';
            app.TM2_33 = uieditfield(app.T23Panel, 'text');
            app.TM2_33.Editable = 'off';
            app.TM2_32 = uieditfield(app.T23Panel, 'text');
            app.TM2_32.Editable = 'off';
            app.TM2_31 = uieditfield(app.T23Panel, 'text');
            app.TM2_31.Editable = 'off';
            app.TM2_30 = uieditfield(app.T23Panel, 'text');
            app.TM2_30.Editable = 'off';
            app.T34Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T34Panel.Title = 'T 3-4';
            app.TM3_00 = uieditfield(app.T34Panel, 'text');
            app.TM3_00.Editable = 'off';
            app.TM3_01 = uieditfield(app.T34Panel, 'text');
            app.TM3_01.Editable = 'off';
            app.TM3_02 = uieditfield(app.T34Panel, 'text');
            app.TM3_02.Editable = 'off';
            app.TM3_03 = uieditfield(app.T34Panel, 'text');
            app.TM3_03.Editable = 'off';
            app.TM3_13 = uieditfield(app.T34Panel, 'text');
            app.TM3_13.Editable = 'off';
            app.TM3_12 = uieditfield(app.T34Panel, 'text');
            app.TM3_12.Editable = 'off';
            app.TM3_11 = uieditfield(app.T34Panel, 'text');
            app.TM3_11.Editable = 'off';
            app.TM3_10 = uieditfield(app.T34Panel, 'text');
            app.TM3_10.Editable = 'off';
            app.TM3_23 = uieditfield(app.T34Panel, 'text');
            app.TM3_23.Editable = 'off';
            app.TM3_22 = uieditfield(app.T34Panel, 'text');
            app.TM3_22.Editable = 'off';
            app.TM3_21 = uieditfield(app.T34Panel, 'text');
            app.TM3_21.Editable = 'off';
            app.TM3_20 = uieditfield(app.T34Panel, 'text');
            app.TM3_20.Editable = 'off';
            app.TM3_33 = uieditfield(app.T34Panel, 'text');
            app.TM3_33.Editable = 'off';
            app.TM3_32 = uieditfield(app.T34Panel, 'text');
            app.TM3_32.Editable = 'off';
            app.TM3_31 = uieditfield(app.T34Panel, 'text');
            app.TM3_31.Editable = 'off';
            app.TM3_30 = uieditfield(app.T34Panel, 'text');
            app.TM3_30.Editable = 'off';
            app.T45Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T45Panel.Title = 'T 4-5';
            app.TM4_00 = uieditfield(app.T45Panel, 'text');
            app.TM4_00.Editable = 'off';
            app.TM4_01 = uieditfield(app.T45Panel, 'text');
            app.TM4_01.Editable = 'off';
            app.TM4_02 = uieditfield(app.T45Panel, 'text');
            app.TM4_02.Editable = 'off';
            app.TM4_03 = uieditfield(app.T45Panel, 'text');
            app.TM4_03.Editable = 'off';
            app.TM4_13 = uieditfield(app.T45Panel, 'text');
            app.TM4_13.Editable = 'off';
            app.TM4_12 = uieditfield(app.T45Panel, 'text');
            app.TM4_12.Editable = 'off';
            app.TM4_11 = uieditfield(app.T45Panel, 'text');
            app.TM4_11.Editable = 'off';
            app.TM4_10 = uieditfield(app.T45Panel, 'text');
            app.TM4_10.Editable = 'off';
            app.TM4_23 = uieditfield(app.T45Panel, 'text');
            app.TM4_23.Editable = 'off';
            app.TM4_22 = uieditfield(app.T45Panel, 'text');
            app.TM4_22.Editable = 'off';
            app.TM4_21 = uieditfield(app.T45Panel, 'text');
            app.TM4_21.Editable = 'off';
            app.TM4_20 = uieditfield(app.T45Panel, 'text');
            app.TM4_20.Editable = 'off';
            app.TM4_33 = uieditfield(app.T45Panel, 'text');
            app.TM4_33.Editable = 'off';
            app.TM4_32 = uieditfield(app.T45Panel, 'text');
            app.TM4_32.Editable = 'off';
            app.TM4_31 = uieditfield(app.T45Panel, 'text');
            app.TM4_31.Editable = 'off';
            app.TM4_30 = uieditfield(app.T45Panel, 'text');
            app.TM4_30.Editable = 'off';
            app.T56Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T56Panel.Title = 'T 5-6';
            app.TM5_00 = uieditfield(app.T56Panel, 'text');
            app.TM5_00.Editable = 'off';
            app.TM5_01 = uieditfield(app.T56Panel, 'text');
            app.TM5_01.Editable = 'off';
            app.TM5_02 = uieditfield(app.T56Panel, 'text');
            app.TM5_02.Editable = 'off';
            app.TM5_03 = uieditfield(app.T56Panel, 'text');
            app.TM5_03.Editable = 'off';
            app.TM5_13 = uieditfield(app.T56Panel, 'text');
            app.TM5_13.Editable = 'off';
            app.TM5_12 = uieditfield(app.T56Panel, 'text');
            app.TM5_12.Editable = 'off';
            app.TM5_11 = uieditfield(app.T56Panel, 'text');
            app.TM5_11.Editable = 'off';
            app.TM5_10 = uieditfield(app.T56Panel, 'text');
            app.TM5_10.Editable = 'off';
            app.TM5_23 = uieditfield(app.T56Panel, 'text');
            app.TM5_23.Editable = 'off';
            app.TM5_22 = uieditfield(app.T56Panel, 'text');
            app.TM5_22.Editable = 'off';
            app.TM5_21 = uieditfield(app.T56Panel, 'text');
            app.TM5_21.Editable = 'off';
            app.TM5_20 = uieditfield(app.T56Panel, 'text');
            app.TM5_20.Editable = 'off';
            app.TM5_33 = uieditfield(app.T56Panel, 'text');
            app.TM5_33.Editable = 'off';
            app.TM5_32 = uieditfield(app.T56Panel, 'text');
            app.TM5_32.Editable = 'off';
            app.TM5_31 = uieditfield(app.T56Panel, 'text');
            app.TM5_31.Editable = 'off';
            app.TM5_30 = uieditfield(app.T56Panel, 'text');
            app.TM5_30.Editable = 'off';
            app.T67Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T67Panel.Title = 'T 6-7';
            app.TM6_00 = uieditfield(app.T67Panel, 'text');
            app.TM6_00.Editable = 'off';
            app.TM6_01 = uieditfield(app.T67Panel, 'text');
            app.TM6_01.Editable = 'off';
            app.TM6_02 = uieditfield(app.T67Panel, 'text');
            app.TM6_02.Editable = 'off';
            app.TM6_03 = uieditfield(app.T67Panel, 'text');
            app.TM6_03.Editable = 'off';
            app.TM6_13 = uieditfield(app.T67Panel, 'text');
            app.TM6_13.Editable = 'off';
            app.TM6_12 = uieditfield(app.T67Panel, 'text');
            app.TM6_12.Editable = 'off';
            app.TM6_11 = uieditfield(app.T67Panel, 'text');
            app.TM6_11.Editable = 'off';
            app.TM6_10 = uieditfield(app.T67Panel, 'text');
            app.TM6_10.Editable = 'off';
            app.TM6_23 = uieditfield(app.T67Panel, 'text');
            app.TM6_23.Editable = 'off';
            app.TM6_22 = uieditfield(app.T67Panel, 'text');
            app.TM6_22.Editable = 'off';
            app.TM6_21 = uieditfield(app.T67Panel, 'text');
            app.TM6_21.Editable = 'off';
            app.TM6_20 = uieditfield(app.T67Panel, 'text');
            app.TM6_20.Editable = 'off';
            app.TM6_33 = uieditfield(app.T67Panel, 'text');
            app.TM6_33.Editable = 'off';
            app.TM6_32 = uieditfield(app.T67Panel, 'text');
            app.TM6_32.Editable = 'off';
            app.TM6_31 = uieditfield(app.T67Panel, 'text');
            app.TM6_31.Editable = 'off';
            app.TM6_30 = uieditfield(app.T67Panel, 'text');
            app.TM6_30.Editable = 'off';
            app.T78Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T78Panel.Title = 'T 7-8';
            app.TM7_00 = uieditfield(app.T78Panel, 'text');
            app.TM7_00.Editable = 'off';
            app.TM7_01 = uieditfield(app.T78Panel, 'text');
            app.TM7_01.Editable = 'off';
            app.TM7_02 = uieditfield(app.T78Panel, 'text');
            app.TM7_02.Editable = 'off';
            app.TM7_03 = uieditfield(app.T78Panel, 'text');
            app.TM7_03.Editable = 'off';
            app.TM7_13 = uieditfield(app.T78Panel, 'text');
            app.TM7_13.Editable = 'off';
            app.TM7_12 = uieditfield(app.T78Panel, 'text');
            app.TM7_12.Editable = 'off';
            app.TM7_11 = uieditfield(app.T78Panel, 'text');
            app.TM7_11.Editable = 'off';
            app.TM7_10 = uieditfield(app.T78Panel, 'text');
            app.TM7_10.Editable = 'off';
            app.TM7_23 = uieditfield(app.T78Panel, 'text');
            app.TM7_23.Editable = 'off';
            app.TM7_22 = uieditfield(app.T78Panel, 'text');
            app.TM7_22.Editable = 'off';
            app.TM7_21 = uieditfield(app.T78Panel, 'text');
            app.TM7_21.Editable = 'off';
            app.TM7_20 = uieditfield(app.T78Panel, 'text');
            app.TM7_20.Editable = 'off';
            app.TM7_33 = uieditfield(app.T78Panel, 'text');
            app.TM7_33.Editable = 'off';
            app.TM7_32 = uieditfield(app.T78Panel, 'text');
            app.TM7_32.Editable = 'off';
            app.TM7_31 = uieditfield(app.T78Panel, 'text');
            app.TM7_31.Editable = 'off';
            app.TM7_30 = uieditfield(app.T78Panel, 'text');
            app.TM7_30.Editable = 'off';
            app.Jacobian = uipanel(app.TransformasyonMatrisleriTab);
            app.Jacobian.Title = 'Jakobiyen Matrisi';
            app.Jacobian_11 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_11.Editable = 'off';
            app.Jacobian_21 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_21.Editable = 'off';
            app.Jacobian_31 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_31.Editable = 'off';
            app.Jacobian_41 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_41.Editable = 'off';
            app.Jacobian_42 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_42.Editable = 'off';
            app.Jacobian_32 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_32.Editable = 'off';
            app.Jacobian_22 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_22.Editable = 'off';
            app.Jacobian_12 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_12.Editable = 'off';
            app.Jacobian_43 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_43.Editable = 'off';
            app.Jacobian_33 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_33.Editable = 'off';
            app.Jacobian_23 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_23.Editable = 'off';
            app.Jacobian_13 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_13.Editable = 'off';
            app.Jacobian_44 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_44.Editable = 'off';
            app.Jacobian_34 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_34.Editable = 'off';
            app.Jacobian_24 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_24.Editable = 'off';
            app.Jacobian_14 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_14.Editable = 'off';
            app.Jacobian_63 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_63.Editable = 'off';
            app.Jacobian_62 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_62.Editable = 'off';
            app.Jacobian_53 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_53.Editable = 'off';
            app.Jacobian_52 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_52.Editable = 'off';
            app.Jacobian_61 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_61.Editable = 'off';
            app.Jacobian_51 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_51.Editable = 'off';
            app.Jacobian_54 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_54.Editable = 'off';
            app.Jacobian_64 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_64.Editable = 'off';
            app.Jacobian_18 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_18.Editable = 'off';
            app.Jacobian_28 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_28.Editable = 'off';
            app.Jacobian_38 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_38.Editable = 'off';
            app.Jacobian_17 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_17.Editable = 'off';
            app.Jacobian_27 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_27.Editable = 'off';
            app.Jacobian_37 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_37.Editable = 'off';
            app.Jacobian_48 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_48.Editable = 'off';
            app.Jacobian_47 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_47.Editable = 'off';
            app.Jacobian_16 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_16.Editable = 'off';
            app.Jacobian_26 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_26.Editable = 'off';
            app.Jacobian_36 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_36.Editable = 'off';
            app.Jacobian_46 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_46.Editable = 'off';
            app.Jacobian_58 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_58.Editable = 'off';
            app.Jacobian_57 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_57.Editable = 'off';
            app.Jacobian_56 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_56.Editable = 'off';
            app.Jacobian_15 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_15.Editable = 'off';
            app.Jacobian_25 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_25.Editable = 'off';
            app.Jacobian_35 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_35.Editable = 'off';
            app.Jacobian_45 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_45.Editable = 'off';
            app.Jacobian_55 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_55.Editable = 'off';
            app.Jacobian_68 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_68.Editable = 'off';
            app.Jacobian_67 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_67.Editable = 'off';
            app.Jacobian_66 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_66.Editable = 'off';
            app.Jacobian_65 = uieditfield(app.Jacobian, 'text');
            app.Jacobian_65.Editable = 'off';
            app.txtOlarakKaydetButton = uibutton(app.TransformasyonMatrisleriTab, 'push');
            app.txtOlarakKaydetButton.ButtonPushedFcn = createCallbackFcn(app, @txtOlarakKaydetButtonPushed, true);
            app.txtOlarakKaydetButton.Text = '.txt Olarak Kaydet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end